classdef kvsettingsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        CustomimportfromfilePanel    matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        DownButton_2                 matlab.ui.control.Button
        UpButton_2                   matlab.ui.control.Button
        RemoveButton_2               matlab.ui.control.Button
        AddButton_3                  matlab.ui.control.Button
        UITable2                     matlab.ui.control.Table
        CancelButton                 matlab.ui.control.Button
        CustomButtonsPanel           matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        DownButton                   matlab.ui.control.Button
        UpButton                     matlab.ui.control.Button
        RemoveButton                 matlab.ui.control.Button
        AddButton_2                  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        FavoriteXAxisVariablesPanel  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        TextArea                     matlab.ui.control.TextArea
        OKButton                     matlab.ui.control.Button
    end

    properties (Access = public)
        kviewAppHandle % handle to the kview
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, kviewAppHandle)
            app.kviewAppHandle = kviewAppHandle;
            app.TextArea.Value = kviewAppHandle.Settings.FavoriteXAxisList;
            app.UITable.Data = kviewAppHandle.Settings.CustomButtonTable;
            app.UITable2.Data = kviewAppHandle.Settings.CustomImportTable;
            movegui(app.UIFigure,"center");
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % set the data for the FavoriteXAxisList
            favoriteXAxisList = string(app.TextArea.Value(:).');
            app.kviewAppHandle.Settings.FavoriteXAxisList = favoriteXAxisList;
            setpref('kview','FavoriteXAxisList',favoriteXAxisList);

            % set the data for the CustomButtonTable
            app.kviewAppHandle.Settings.CustomButtonTable = app.UITable.Data;
            setpref('kview','CustomButtonTable',app.UITable.Data);

            % set the data for the CustomImportTable
            app.kviewAppHandle.Settings.CustomImportTable = app.UITable2.Data;
            setpref('kview','CustomImportTable',app.UITable2.Data);

            % close the GUI
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            app.UITable.Data = [app.UITable.Data; {'','','',''}];
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            selectedRow = app.UITable.Selection;
            app.UITable.Data(selectedRow,:) = [];
        end

        % Button pushed function: AddButton_3
        function AddButton_3Pushed(app, event)
            app.UITable2.Data = [app.UITable2.Data; {'','',''}];
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
            selectedRow = app.UITable2.Selection;
            app.UITable2.Data(selectedRow,:) = [];
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            rowList = 1:numel(app.UITable.Data(:,1));
            selectedRowList = app.UITable.Selection;
            [newRowOrder, newSelection] = moveElementInArray(rowList,selectedRowList,"up");
            app.UITable.Data = app.UITable.Data(newRowOrder,:);
            app.UITable.Selection = newSelection;
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            rowList = 1:numel(app.UITable.Data(:,1));
            selectedRowList = app.UITable.Selection;
            [newRowOrder, newSelection] = moveElementInArray(rowList,selectedRowList,"down");
            app.UITable.Data = app.UITable.Data(newRowOrder,:);
            app.UITable.Selection = newSelection;
        end

        % Button pushed function: UpButton_2
        function UpButton_2Pushed(app, event)
            rowList = 1:numel(app.UITable2.Data(:,1));
            selectedRowList = app.UITable2.Selection;
            [newRowOrder, newSelection] = moveElementInArray(rowList,selectedRowList,"up");
            app.UITable2.Data = app.UITable2.Data(newRowOrder,:);
            app.UITable2.Selection = newSelection;
        end

        % Button pushed function: DownButton_2
        function DownButton_2Pushed(app, event)
            rowList = 1:numel(app.UITable2.Data(:,1));
            selectedRowList = app.UITable2.Selection;
            [newRowOrder, newSelection] = moveElementInArray(rowList,selectedRowList,"down");
            app.UITable2.Data = app.UITable2.Data(newRowOrder,:);
            app.UITable2.Selection = newSelection;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FavoriteXAxisVariablesPanel = uipanel(app.GridLayout);
            app.FavoriteXAxisVariablesPanel.Tooltip = {'The variables listed here can be selected by righ clicking on the xaxis "set default" button.'};
            app.FavoriteXAxisVariablesPanel.Title = 'Favorite X Axis Variables';
            app.GridLayout2 = uigridlayout(app.FavoriteXAxisVariablesPanel);
            app.TextArea = uitextarea(app.GridLayout2);
            app.CustomButtonsPanel = uipanel(app.GridLayout);
            app.CustomButtonsPanel.Title = 'Custom Buttons';
            app.GridLayout3 = uigridlayout(app.CustomButtonsPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Group'; 'Text'; 'Function'; 'Tooltip'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true false];
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.UITable.Multiselect = 'off';
            app.AddButton_2 = uibutton(app.GridLayout3, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.RemoveButton = uibutton(app.GridLayout3, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.UpButton = uibutton(app.GridLayout3, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.GridLayout3, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CustomimportfromfilePanel = uipanel(app.GridLayout);
            app.CustomimportfromfilePanel.Title = 'Custom import from file';
            app.GridLayout4 = uigridlayout(app.CustomimportfromfilePanel);
            app.UITable2 = uitable(app.GridLayout4);
            app.UITable2.ColumnName = {'Extension'; 'Text'; 'Function'};
            app.UITable2.RowName = {};
            app.UITable2.SelectionType = 'row';
            app.UITable2.ColumnEditable = true;
            app.UITable2.Multiselect = 'off';
            app.AddButton_3 = uibutton(app.GridLayout4, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButton_3Pushed, true);
            app.AddButton_3.Text = 'Add';
            app.RemoveButton_2 = uibutton(app.GridLayout4, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.UpButton_2 = uibutton(app.GridLayout4, 'push');
            app.UpButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpButton_2Pushed, true);
            app.UpButton_2.Text = 'Up';
            app.DownButton_2 = uibutton(app.GridLayout4, 'push');
            app.DownButton_2.ButtonPushedFcn = createCallbackFcn(app, @DownButton_2Pushed, true);
            app.DownButton_2.Text = 'Down';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kvsettingsGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end